classdef SpectralTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralProcessingToolUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ImportededsignalButton          matlab.ui.control.ToggleButton
        DetrendedsignalButton           matlab.ui.control.ToggleButton
        DetrendingSwitch                matlab.ui.control.Switch
        DetrendingSwitchLabel           matlab.ui.control.Label
        EnvelopeSwitch                  matlab.ui.control.Switch
        EnvelopeSwitchLabel             matlab.ui.control.Label
        DegreeofpolynomialtrendEditField  matlab.ui.control.NumericEditField
        DegreeofpolynomialtrendEditFieldLabel  matlab.ui.control.Label
        AnalysisButton                  matlab.ui.control.Button
        ChoosefileDropDown              matlab.ui.control.DropDown
        ChoosefileDropDownLabel         matlab.ui.control.Label
        UIAxes_InsFreq                  matlab.ui.control.UIAxes
        UIAxes_Env                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FrequenceDomainTab              matlab.ui.container.Tab
        WindowlengthofsignallengthSlider  matlab.ui.control.Slider
        WindowlengthofsignallengthSliderLabel  matlab.ui.control.Label
        OverlapfactorEditField          matlab.ui.control.NumericEditField
        OverlapfactorLabel              matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        WindowNum                       matlab.ui.control.NumericEditField
        PSDmetodButtonGroup             matlab.ui.container.ButtonGroup
        WelchButton                     matlab.ui.control.ToggleButton
        PeriodogramButton               matlab.ui.control.ToggleButton
        WindowButtonGroup               matlab.ui.container.ButtonGroup
        HammingButton                   matlab.ui.control.ToggleButton
        HannButton                      matlab.ui.control.ToggleButton
        UIAxes_Freq                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        file;
        detrend;
        signal;
        fs;
        inst_amplitude;
        inst_phase;
        inst_freq;
        window_length;
        detrending;
        t_diff;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosefileDropDown
        function ChoosefileDropDownValueChanged(app, event)
            value = app.ChoosefileDropDown.Value;
            switch value
                case "Import data"
                case "Example 1"
                    app.file = importdata("signals\Polynomal_Trended_1.mat");
                case "Example 2"
                    app.file = importdata("signals\Sawtooth_Trended.mat");
                case "Example 3"
                    app.file = importdata("signals\Sin_Noised.mat");
                case "Choose from disk"
                    [app.file,path] = uigetfile('*.mat');
                    app.file = importdata(fullfile(path,app.file));
                    app.ChoosefileDropDown.Value="Import data";
            end

            plot(app.UIAxes,app.file.t,app.file.x);  
            app.fs = 1/(app.file.t(2)-app.file.t(1));
        
        end

        % Callback function
        function UIAxesButtonDown(app, event)
         plot(app.UIAxes,app.file.t,app.file.x);
        
        end

        % Value changed function: DetrendingSwitch
        function DetrendingSwitchValueChanged(app, event)
            value = app.DetrendingSwitch.Value;
            if value == "On"
                app.DegreeofpolynomialtrendEditField.Enable = "on";
                app.ButtonGroup.Enable = "on";
                app.ButtonGroup.Visible = "on";
              

            elseif value == "Off"
                app.DegreeofpolynomialtrendEditField.Enable = "off";
                app.ButtonGroup.Enable = "off";
                app.ButtonGroup.Visible = "off";
                plot(app.UIAxes,app.file.t,app.file.x); 
            end
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            addpath("func\");
            app.detrending =0;
            if app.DetrendingSwitch.Value == "On"
                [app.detrend,trend] = Detrend(app.file.t,app.file.x,app.DegreeofpolynomialtrendEditField.Value);
                plot(app.UIAxes,app.file.t,app.file.x);  
                app.UIAxes.NextPlot='add';
                plot(app.UIAxes,app.file.t,trend,"LineStyle","--");
                plot(app.UIAxes,app.file.t,app.detrend);
                app.UIAxes.NextPlot='replace';
                app.signal=app.detrend;
                app.UIAxes.Title.String = "Data in Time Domain";
            else
                app.signal = app.file.x;
            end
            if app.EnvelopeSwitch.Value == "On"
                if (app.ImportededsignalButton.Value == true)
                    [yupper,  ylower] = Envelope(app.file.x);
                    plot(app.UIAxes_Env,app.file.t,app.file.x);
                    app.UIAxes_Env.NextPlot='add';
                    plot(app.UIAxes_Env,app.file.t,ylower);
                    plot(app.UIAxes_Env,app.file.t,yupper);
                    app.UIAxes_Env.NextPlot='replace';
                    app.UIAxes_Env.Title.String = "Envelope";
                elseif (app.DetrendedsignalButton.Value == true)
                    [yupper,  ylower] = Envelope(app.detrend);
                    plot(app.UIAxes_Env,app.file.t,app.detrend);
                    app.UIAxes_Env.NextPlot='add';
                    plot(app.UIAxes_Env,app.file.t,ylower);
                    plot(app.UIAxes_Env,app.file.t,yupper);
                    app.UIAxes_Env.NextPlot='replace';
                    app.UIAxes_Env.Title.String = "Envelope";
                end
                
            end
            if app.DetrendingSwitch.Value == "On"
                if (app.ImportededsignalButton.Value == true)
                    [app.inst_amplitude,app.inst_phase,app.inst_freq]...
                        = Instinfo(app.file.x,app.fs);
                    T_diff =  app.file.t(1:end-1)-1/app.fs/2;
                    plot(app.UIAxes_InsFreq, T_diff ,app.inst_freq);
                elseif (app.DetrendedsignalButton.Value == true)
                    [app.inst_amplitude,app.inst_phase,app.inst_freq] =...
                        Instinfo(app.signal,app.fs);
                    T_diff =  app.file.t(1:end-1)-1/app.fs/2;
                    plot(app.UIAxes_InsFreq, T_diff,app.inst_freq);
                end
                app.detrending =1;
            end
            [app.inst_amplitude,app.inst_phase,app.inst_freq]=...
                Instinfo(app.file.x,app.fs);
            T_diff =  app.file.t(1:end-1)-1/app.fs/2;
            plot(app.UIAxes_InsFreq, T_diff ,app.inst_freq);
            app.t_diff=T_diff;
            axis(app.UIAxes_InsFreq, 'tight');
        end

        % Value changed function: EnvelopeSwitch
        function EnvelopeSwitchValueChanged(app, event)
            value = app.EnvelopeSwitch.Value;
        if (value == "Off")
            cla(app.UIAxes_Env,'reset')
        end
        end

        % Selection changed function: PSDmetodButtonGroup
        function PSDmetodButtonGroupSelectionChanged(app, event)
            if (app.WelchButton.Value == true)
                app.WindowButtonGroup.Visible = "on";
                app.WindowButtonGroup.Enable = "on";
                app.WindowNum.Visible = "on";
                app.WindowlengthofsignallengthSlider.Visible = "on";
                app.WindowlengthofsignallengthSliderLabel.Visible ="on";
                app.OverlapfactorEditField.Visible = "on";
                app.OverlapfactorLabel.Visible ="on";
                app.WindowNum.Enable = "on";
                app.WindowlengthofsignallengthSlider.Enable = "on";
                app.WindowlengthofsignallengthSliderLabel.Enable ="on";
                app.OverlapfactorEditField.Enable = "on";
                app.OverlapfactorLabel.Enable ="on";
                app.window_length=app.WindowNum.Value;
                app.UIAxes_Freq.Title.String = "Welch Power Spectral Density Estimate";
                app.UIAxes_Freq.XLabel.String =  "Frequency (Hz)";
                app.UIAxes_Freq.YLabel.String =  "Power / Frequency (db/Hz)";

            elseif (app.PeriodogramButton.Value == true)
             app.WindowButtonGroup.Visible = "off";
                app.WindowButtonGroup.Enable = "off";
                app.WindowNum.Visible = "off";
                app.WindowlengthofsignallengthSlider.Visible = "off";
                app.WindowlengthofsignallengthSliderLabel.Visible ="off";
                app.OverlapfactorEditField.Visible = "off";
                app.OverlapfactorLabel.Visible ="off";
                app.WindowNum.Enable = "off";
                app.WindowlengthofsignallengthSlider.Enable = "off";
                app.WindowlengthofsignallengthSliderLabel.Enable ="off";
                app.OverlapfactorEditField.Enable = "off";
                app.OverlapfactorLabel.Enable ="off";
                app.UIAxes_Freq.Title.String = "Periodogram";
                app.UIAxes_Freq.XLabel.String =  "Frequency (Hz)";
                app.UIAxes_Freq.YLabel.String =  "Power / Frequency (db/Hz)";

            end
            
               
        end

        % Value changed function: WindowlengthofsignallengthSlider
        function WindowlengthofsignallengthSliderValueChanged(app, event)
        value = app.WindowlengthofsignallengthSlider.Value;
            app.window_length = value;
            app.WindowNum.Value =value;
            app.WindowlengthofsignallengthSlider.Value=value;
            if (app.window_length == 0)
                app.window_length = 1;
            end
        end

        % Value changed function: WindowNum
        function WindowNumValueChanged(app, event)
            value = app.WindowNum.Value;
            app.window_length = value;
            app.WindowNum.Value =value;
            app.WindowlengthofsignallengthSlider.Value=value;
             if (app.window_length == 0)
                app.window_length = 1;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            addpath("func\");
            Data=app.signal;
            if (app.WelchButton.Value == true)
                if (app.HannButton.Value == true)
                    Window=Hann(ceil(app.window_length/100*length(Data)));
                elseif (app.HammingButton.Value == true)
                    Window=Hamming(ceil(app.window_length/100*length(Data)));
                end
                OverlapFactor=app.OverlapfactorEditField.Value;
                SamplingFrequency=app.fs;
                [Power, Frequency] = WelchPSD(Data, [], Window, OverlapFactor, SamplingFrequency);
                plot(app.UIAxes_Freq,Frequency, 10*log10(abs(Power)))
            elseif (app.PeriodogramButton.Value == true)
                [Power] = Periodogram(Data,app.fs);
                Frequency= linspace(0,app.fs,length(Power));
                plot(app.UIAxes_Freq,Frequency,10*log10(Power))
                xlim(app.UIAxes_Freq, [0, app.fs/2]);
            end
            
            frequency = zeros(1,length(app.file.t));
            for k = 1:length(Frequency)
            frequency(k) = Frequency(k);
            end
            Frequency = frequency;

            power = zeros(1,length(app.file.t));
            for k = 1:length(Power)
            power(k) = Power(k);
            end
            Power = power;

            T_diff = zeros(1,length(app.file.t));
            for k = 1:length(app.t_diff)
            T_diff(k) = app.t_diff(k);
            end
            app.t_diff = T_diff;

            Inst_Freq = zeros(1,length(app.file.t));
            for k = 1:length(app.inst_freq)
            Inst_Freq(k) = app.inst_freq(k);
            end
            app.inst_freq = Inst_Freq;

            if app.PeriodogramButton.Value == true
            app.UITable.ColumnName =["Time","Orginal signal","T_diff","Inst Freq","X Periodogram","Y Periodogram"];
            app.UITable.Data = [app.file.t',app.file.x',app.t_diff',app.inst_freq',Frequency',(10*log10(Power))'];
            end
             if app.WelchButton.Value == true
            app.UITable.ColumnName =["Time","Orginal signal","T_diff","Inst Freq","X Welch","Y Welch"];
            app.UITable.Data = [app.file.t',app.file.x',app.t_diff',app.inst_freq',Frequency',(10*log10(Power))'];
            end

            if (app.detrending == 1)
                 if app.PeriodogramButton.Value == true
                    app.UITable.ColumnName =["Time","Orginal signal",...
                        "Detrended signal","T_diff","Inst Freq",...
                        "X Periodogram","Y Periodogram"];
                    app.UITable.Data = [app.file.t',app.file.x',...
                        app.detrend',app.t_diff',app.inst_freq',...
                        Frequency',(10*log10(Power))'];
                end
                
                 if app.WelchButton.Value == true
                    app.UITable.ColumnName =["Time","Orginal signal",...
                        "Detrended signal","T_diff","Inst Freq",...
                        "X Welch","Y Welch"];
                    app.UITable.Data = [app.file.t',app.file.x',...
                        app.detrend',app.t_diff',app.inst_freq',...
                        Frequency',(10*log10(Power))'];
                end
            end

            app.SavedataButton.Enable = true;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            filter = {'*.csv';'*.txt';'*.xlsx'};
            [save_file,save_path] = uiputfile(filter); 
            writecell(app.UITable.ColumnName',fullfile(save_path,save_file),'Delimiter','comma');
            writematrix(app.UITable.Data,fullfile(save_path,save_file),'Delimiter','comma','WriteMode','append');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SpectralProcessingToolUIFigure = uifigure('Visible', 'off');
            app.SpectralProcessingToolUIFigure.Name = 'Spectral Processing Tool';
            app.SpectralProcessingToolUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.TabGroup = uitabgroup(app.SpectralProcessingToolUIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Data in Time Domain ')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Amplitude [~]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_Env = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_Env, 'Envelope')
            xlabel(app.UIAxes_Env, 'Time [s]')
            ylabel(app.UIAxes_Env, 'Amplitude [~]')
            zlabel(app.UIAxes_Env, 'Z')
            app.UIAxes_InsFreq = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_InsFreq, 'Instantaneous Frequency')
            xlabel(app.UIAxes_InsFreq, 'Time [s]')
            ylabel(app.UIAxes_InsFreq, 'Frequency [Hz]')
            zlabel(app.UIAxes_InsFreq, 'Z')
            app.ChoosefileDropDownLabel = uilabel(app.TimeDomainTab);
            app.ChoosefileDropDownLabel.Text = 'Choose file';
            app.ChoosefileDropDown = uidropdown(app.TimeDomainTab);
            app.ChoosefileDropDown.Items = {'Import data', 'Example 1', 'Example 2', 'Example 3', 'Choose from disk'};
            app.ChoosefileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefileDropDownValueChanged, true);
            app.ChoosefileDropDown.Placeholder = '...';
            app.ChoosefileDropDown.Value = 'Import data';
            app.AnalysisButton = uibutton(app.TimeDomainTab, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.DegreeofpolynomialtrendEditFieldLabel = uilabel(app.TimeDomainTab);
            app.DegreeofpolynomialtrendEditFieldLabel.Text = 'Degree of polynomial trend';
            app.DegreeofpolynomialtrendEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.DegreeofpolynomialtrendEditField.Limits = [0 Inf];
            app.DegreeofpolynomialtrendEditField.RoundFractionalValues = 'on';
            app.DegreeofpolynomialtrendEditField.ValueDisplayFormat = '%1g';
            app.DegreeofpolynomialtrendEditField.Enable = 'off';
            app.DegreeofpolynomialtrendEditField.Value = 1;
            app.EnvelopeSwitchLabel = uilabel(app.TimeDomainTab);
            app.EnvelopeSwitchLabel.Text = 'Envelope';
            app.EnvelopeSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.EnvelopeSwitch.ValueChangedFcn = createCallbackFcn(app, @EnvelopeSwitchValueChanged, true);
            app.DetrendingSwitchLabel = uilabel(app.TimeDomainTab);
            app.DetrendingSwitchLabel.Text = 'Detrending';
            app.DetrendingSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.DetrendingSwitch.ValueChangedFcn = createCallbackFcn(app, @DetrendingSwitchValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.TimeDomainTab);
            app.ButtonGroup.Enable = 'off';
            app.ButtonGroup.Visible = 'off';
            app.DetrendedsignalButton = uitogglebutton(app.ButtonGroup);
            app.DetrendedsignalButton.Text = 'Detrended signal';
            app.ImportededsignalButton = uitogglebutton(app.ButtonGroup);
            app.ImportededsignalButton.Text = 'Importeded signal';
            app.ImportededsignalButton.Value = true;
            app.FrequenceDomainTab = uitab(app.TabGroup);
            app.FrequenceDomainTab.Title = 'Frequence Domain';
            app.UIAxes_Freq = uiaxes(app.FrequenceDomainTab);
            title(app.UIAxes_Freq, 'Title')
            xlabel(app.UIAxes_Freq, 'Frequence [Hz]')
            ylabel(app.UIAxes_Freq, 'Amplitude [~]')
            zlabel(app.UIAxes_Freq, 'Z')
            app.WindowButtonGroup = uibuttongroup(app.FrequenceDomainTab);
            app.WindowButtonGroup.Enable = 'off';
            app.WindowButtonGroup.Title = 'Window';
            app.WindowButtonGroup.Visible = 'off';
            app.HannButton = uitogglebutton(app.WindowButtonGroup);
            app.HannButton.Text = 'Hann';
            app.HannButton.Value = true;
            app.HammingButton = uitogglebutton(app.WindowButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.PSDmetodButtonGroup = uibuttongroup(app.FrequenceDomainTab);
            app.PSDmetodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PSDmetodButtonGroupSelectionChanged, true);
            app.PSDmetodButtonGroup.Title = 'PSD metod';
            app.PeriodogramButton = uitogglebutton(app.PSDmetodButtonGroup);
            app.PeriodogramButton.Text = 'Periodogram';
            app.PeriodogramButton.Value = true;
            app.WelchButton = uitogglebutton(app.PSDmetodButtonGroup);
            app.WelchButton.Text = 'Welch';
            app.WindowNum = uieditfield(app.FrequenceDomainTab, 'numeric');
            app.WindowNum.Limits = [0 100];
            app.WindowNum.ValueDisplayFormat = '%.2f';
            app.WindowNum.ValueChangedFcn = createCallbackFcn(app, @WindowNumValueChanged, true);
            app.WindowNum.Enable = 'off';
            app.WindowNum.Visible = 'off';
            app.WindowNum.Value = 50;
            app.CalculateButton = uibutton(app.FrequenceDomainTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.FrequenceDomainTab);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.SavedataButton = uibutton(app.FrequenceDomainTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            app.OverlapfactorLabel = uilabel(app.FrequenceDomainTab);
            app.OverlapfactorLabel.Enable = 'off';
            app.OverlapfactorLabel.Visible = 'off';
            app.OverlapfactorLabel.Text = 'Overlap factor (%)';
            app.OverlapfactorEditField = uieditfield(app.FrequenceDomainTab, 'numeric');
            app.OverlapfactorEditField.Limits = [0 1];
            app.OverlapfactorEditField.ValueDisplayFormat = '%11.11g';
            app.OverlapfactorEditField.Enable = 'off';
            app.OverlapfactorEditField.Visible = 'off';
            app.OverlapfactorEditField.Value = 0.5;
            app.WindowlengthofsignallengthSliderLabel = uilabel(app.FrequenceDomainTab);
            app.WindowlengthofsignallengthSliderLabel.Enable = 'off';
            app.WindowlengthofsignallengthSliderLabel.Visible = 'off';
            app.WindowlengthofsignallengthSliderLabel.Text = {'Window length'; '(% of signal length )'};
            app.WindowlengthofsignallengthSlider = uislider(app.FrequenceDomainTab);
            app.WindowlengthofsignallengthSlider.MajorTicks = [0 25 50 75 100];
            app.WindowlengthofsignallengthSlider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.WindowlengthofsignallengthSlider.ValueChangedFcn = createCallbackFcn(app, @WindowlengthofsignallengthSliderValueChanged, true);
            app.WindowlengthofsignallengthSlider.MinorTicks = [0 5.001 10.001 15.001 20.001 25.001 30.001 35.001 40.001 45.001 50.001 55.001 60.001 65.001 70.001 75.001 80.001 85.001 90.001 95.001 100];
            app.WindowlengthofsignallengthSlider.Enable = 'off';
            app.WindowlengthofsignallengthSlider.Visible = 'off';
            app.WindowlengthofsignallengthSlider.Value = 50;
            % Show the figure after all components are created
            app.SpectralProcessingToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralProcessingToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralProcessingToolUIFigure)
        end
    end
end